classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Slider       matlab.ui.control.Slider
        SliderLabel  matlab.ui.control.Label
        UIAxes3      matlab.ui.control.UIAxes
        UIAxes2      matlab.ui.control.UIAxes
        UIAxes       matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        function outputImage = mixing(~, imageOne, imageTwo, kFactor)
            im1 = double(imageOne);
            im2 = double(imageTwo);

            [rows, cols, channels] = size(im1);
            tempImage = zeros(rows, cols, channels);

            for r = 1:rows
                for c = 1:cols
                    for ch = 1:channels
                        pixelVal = (kFactor * im1(r, c, ch)) + ((1 - kFactor) * im2(r, c, ch));
                        tempImage(r, c, ch) = pixelVal;
                    end
                end
            end

            outputImage = uint8(tempImage);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            k = event.Value;

            img1 = imread('img.jpg');
            img2 = imread('star.jpg');

            result = app.mixing(img1, img2, k);
            
            imshow(img1, 'Parent', app.UIAxes);
            imshow(img2, 'Parent', app.UIAxes3);
            imshow(result, 'Parent', app.UIAxes2);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'mix')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            zlabel(app.UIAxes3, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end